classdef ecgClassificationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Tree                          matlab.ui.container.CheckBoxTree
        Node                          matlab.ui.container.TreeNode
        Node2                         matlab.ui.container.TreeNode
        Node3                         matlab.ui.container.TreeNode
        Node4                         matlab.ui.container.TreeNode
        GridLayout                    matlab.ui.container.GridLayout
        TimeSeriesAnalysisPanel       matlab.ui.container.Panel
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        PatientInformationPanel       matlab.ui.container.Panel
        PatientInfo                   matlab.ui.control.Label
        ResetButton                   matlab.ui.control.Button
        BreathRateEditField           matlab.ui.control.NumericEditField
        BreathRateEditFieldLabel      matlab.ui.control.Label
        SelectPatientIdDropDown       matlab.ui.control.DropDown
        PatientIDLabel                matlab.ui.control.Label
        HeartConditionEditField       matlab.ui.control.EditField
        HeartConditionEditFieldLabel  matlab.ui.control.Label
        ConfidenceEditField           matlab.ui.control.NumericEditField
        ScoreLabel                    matlab.ui.control.Label
        HeartRateEditField            matlab.ui.control.NumericEditField
        HeartRateEditFieldLabel       matlab.ui.control.Label
        ClassificationAlgorithmResponseLamp  matlab.ui.control.Lamp
        ClassificationAlgorithmResponseLampLabel  matlab.ui.control.Label
        RunAnalysisButton             matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        SignalLogo                    matlab.ui.control.Image
        MatlabLogo                    matlab.ui.control.Image
        Copyrightc2023TheMathWorksIncLabel  matlab.ui.control.Label
        ECGClassificationAppLabel     matlab.ui.control.Label
    end

    properties (Access = private)
        patientList         % Array of patient objects
        resultsList         % Array of edgAnalysis objects (1 per patient)
        currentPatientId    % Current patient object loaded in memory
    end
    
    methods (Access = private)
        function updateAnalysis(app, pat, resData)
            % Update text fields
            app.HeartConditionEditField.Value = resData.label;
            app.ConfidenceEditField.Value = resData.score*100;
            app.HeartRateEditField.Value = resData.heartRate;
            app.BreathRateEditField.Value = resData.breathRate;

            % Change bulb color
            if ~(resData.heartRate == -1)
                app.ClassificationAlgorithmResponseLamp.Color = 'g'; 
            else 
                app.ClassificationAlgorithmResponseLamp.Color = 'r'; 
            end
            
            % Update canvases
            plot(app.UIAxes2, pat.data.time, resData.heartRateSig)
            axis(app.UIAxes2,'tight');
            
            % Plot results
            plot(app.UIAxes2_2, pat.data.time, resData.breathRateSig)
            axis(app.UIAxes,'tight');

            % Enable reset button
            app.ResetButton.Visible = "on";
        end
        function resetDisplayInfo(app)
            app.ResetButton.Visible = "off";
            app.RunAnalysisButton.Enable = "on";
            app.ClassificationAlgorithmResponseLamp.Color = [1 1 1];
            app.BreathRateEditField.Value = 0;
            app.HeartRateEditField.Value = 0;
            app.HeartConditionEditField.Value = 'N/A';
            app.ConfidenceEditField.Value = 0;
            
            % Clear analysis plots
            cla(app.UIAxes2)
            cla(app.UIAxes2_2)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('nameslist.mat','nameslist')
            ecgData = load('ECGData.mat');
            
            % Construct object and initialize list
            freq = ecgData.Fs;
            % t = (0:1/Fs:(32-1/Fs));
            n = 20;
            app.patientList = cell(n, 1);
            app.resultsList = cell(n, 1);
            
            % Initialize patient list
            fln = repmat(" ", n+1, 1);
            app.SelectPatientIdDropDown.ItemsData = (0:n)';

            for id = 1:n
                % Generate random names and patient id's
                age = round(max(20, min(40 + 25.*randn(1), 120)));
                nameIdx = randi([1 100], [2 1]);
                first = nameslist(nameIdx(1), 1);
                last = nameslist(nameIdx(1), 2);
                fln(id+1) = strcat(first," ",last," (ID=",string(id),")");
                
                % Extract ECG data based on patient's id
                signal = ecgData.ECGData.Data(id, 1:4096);
                
                % Create patient object
                app.patientList{id} = patient(first, last, age, id, signal, freq);
            end

            app.SelectPatientIdDropDown.Items = fln;

            % Check if GPU device is available; otherwise, create a parpool
            % if (gpuDeviceCount == 0) && isempty(gcp('nocreate'))
            %     parpool("Processes")
            % end

            % Plot EGC data for patient
            axis(app.UIAxes,'tight');
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            % Lock button
            app.RunAnalysisButton.Enable = "off";
            
            % Check if analysis results are available
            idx = app.currentPatientId;
            currPatient = app.patientList{idx};

            % Start analysis
            f = waitbar(0,'Preprocessing ECG data...', 'Name','EGC Analysis');
            
            % Run DL analysis
            waitbar(1/2,f,'Running model...');
            if currPatient.processed == false
                app.resultsList{idx} = ecgAnalysis(currPatient);
                app.patientList{idx}.processed = true;
            end
            
            waitbar(2/2,f,'Updating results...');
            updateAnalysis(app, app.patientList{idx}, app.resultsList{idx});

            % Close waitbar
            close(f)
        end

        % Value changed function: SelectPatientIdDropDown
        function PatientNumberSliderValueChanged(app, event)
            % Current patient info
            idx = app.SelectPatientIdDropDown.Value;
            if ~isnumeric(idx)
                id = str2double(idx);
            else
                id = idx;
            end
            if id > 0
                currPatient = app.patientList{id};
                currResults = app.resultsList{id};
                first = string(currPatient.first);
                last = string(currPatient.last);
                app.PatientInfo.Text = "";
                txt = sprintf("Name: %s %s,   Age: %d", first, last, currPatient.age);
                app.PatientInfo.Text = txt;
                
                % Plot ECG data
                plot(app.UIAxes, currPatient.data.time, currPatient.data.signal)
                axis(app.UIAxes,'tight');
    
                % Check whether patient's ECG data has been processed
                if currPatient.processed == false
                    app.currentPatientId = id;
                    resetDisplayInfo(app);
                else
                    updateAnalysis(app, currPatient, currResults);
                end
            else
                resetDisplayInfo(app);
                cla(app.UIAxes)
            end
        end

        % Clicked callback: SelectPatientIdDropDown
        function SelectPatientIdDropDownClicked(app, event)
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            idx = app.currentPatientId;
            app.resultsList{idx}.label = "";
            app.resultsList{idx}.score = 0;
            app.resultsList{idx}.heartRate = -1;
            app.resultsList{idx}.breathRate = -1;
            app.resultsList{idx}.heartRateSig = [];
            app.resultsList{idx}.breathRateSig = [];
            app.patientList{idx}.processed = false;
            resetDisplayInfo(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ECGClassificationAppLabel = uilabel(app.GridLayout);
            app.ECGClassificationAppLabel.Text = 'ECG Classification App';
            app.Copyrightc2023TheMathWorksIncLabel = uilabel(app.GridLayout);
            app.Copyrightc2023TheMathWorksIncLabel.Text = 'Copyright (c) 2023 - The MathWorks, Inc. ';
            app.MatlabLogo = uiimage(app.GridLayout);
            app.MatlabLogo.ImageSource = fullfile(pathToMLAPP, 'Images', 'Matlab_Logo.png');
            app.SignalLogo = uiimage(app.GridLayout);
            app.SignalLogo.ImageSource = fullfile(pathToMLAPP, 'Images', 'signal-logo.png');
            app.PatientInformationPanel = uipanel(app.GridLayout);
            app.PatientInformationPanel.Title = 'Patient Information';
            app.UIAxes = uiaxes(app.PatientInformationPanel);
            title(app.UIAxes, 'ECG Signal [Amplitude]')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude (V)')
            zlabel(app.UIAxes, 'Z')
            app.RunAnalysisButton = uibutton(app.PatientInformationPanel, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Enable = 'off';
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.ClassificationAlgorithmResponseLampLabel = uilabel(app.PatientInformationPanel);
            app.ClassificationAlgorithmResponseLampLabel.Text = 'Classification Algorithm Response';
            app.ClassificationAlgorithmResponseLamp = uilamp(app.PatientInformationPanel);
            app.HeartRateEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.HeartRateEditFieldLabel.Text = 'Heart Rate';
            app.HeartRateEditField = uieditfield(app.PatientInformationPanel, 'numeric');
            app.HeartRateEditField.Limits = [-1 500];
            app.HeartRateEditField.Editable = 'off';
            app.HeartRateEditField.Tooltip = {'Heart rate calculated from signal processing workflow'};
            app.ScoreLabel = uilabel(app.PatientInformationPanel);
            app.ScoreLabel.Text = 'Confidence %';
            app.ConfidenceEditField = uieditfield(app.PatientInformationPanel, 'numeric');
            app.ConfidenceEditField.Limits = [0 100];
            app.ConfidenceEditField.Editable = 'off';
            app.HeartConditionEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.HeartConditionEditFieldLabel.Text = 'Heart Condition';
            app.HeartConditionEditField = uieditfield(app.PatientInformationPanel, 'text');
            app.HeartConditionEditField.Editable = 'off';
            app.HeartConditionEditField.Tooltip = {'Displays heart condition based on deep learning model'};
            app.HeartConditionEditField.Value = 'N/A';
            app.PatientIDLabel = uilabel(app.PatientInformationPanel);
            app.PatientIDLabel.Text = 'Patient ID';
            app.SelectPatientIdDropDown = uidropdown(app.PatientInformationPanel);
            app.SelectPatientIdDropDown.Items = {};
            app.SelectPatientIdDropDown.ValueChangedFcn = createCallbackFcn(app, @PatientNumberSliderValueChanged, true);
            app.SelectPatientIdDropDown.ClickedFcn = createCallbackFcn(app, @SelectPatientIdDropDownClicked, true);
            app.SelectPatientIdDropDown.Value = {};
            app.BreathRateEditFieldLabel = uilabel(app.PatientInformationPanel);
            app.BreathRateEditFieldLabel.Text = 'Breath Rate';
            app.BreathRateEditField = uieditfield(app.PatientInformationPanel, 'numeric');
            app.BreathRateEditField.Limits = [-1 200];
            app.BreathRateEditField.Editable = 'off';
            app.BreathRateEditField.Tooltip = {'Breath rate inferred from deep learning model'};
            app.ResetButton = uibutton(app.PatientInformationPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'Images', 'trash-can.png');
            app.ResetButton.Visible = 'off';
            app.ResetButton.Tooltip = {'Reset ECG analysis results from patient'};
            app.ResetButton.Text = '';
            app.PatientInfo = uilabel(app.PatientInformationPanel);
            app.PatientInfo.Tooltip = {'Information of current patience'};
            app.PatientInfo.Text = '';
            app.TimeSeriesAnalysisPanel = uipanel(app.GridLayout);
            app.TimeSeriesAnalysisPanel.Title = 'Time-Series Analysis';
            app.UIAxes2 = uiaxes(app.TimeSeriesAnalysisPanel);
            title(app.UIAxes2, 'Heart Rate [Amplitude]')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Normalized Amplitude ')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.TimeSeriesAnalysisPanel);
            title(app.UIAxes2_2, 'Breath Rate [Amplitude]')
            xlabel(app.UIAxes2_2, 'Time (s)')
            ylabel(app.UIAxes2_2, 'Normalized Amplitude ')
            zlabel(app.UIAxes2_2, 'Z')
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ecgClassificationApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end